classdef MatlabExperimentHub < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        Panel              matlab.ui.container.Panel
        GridLayout_1       matlab.ui.container.GridLayout
        Label              matlab.ui.control.Label
        Label_1            matlab.ui.control.Label
        SelectExperiment1  matlab.ui.control.Button
        SelectExperiment2  matlab.ui.control.Button
        SelectExperiment3  matlab.ui.control.Button
        SelectExperiment4  matlab.ui.control.Button
        Label_2            matlab.ui.control.Label
        Button             matlab.ui.control.Button
        MainGrid           matlab.ui.container.GridLayout
        Heading            matlab.ui.control.Label
        Scope              matlab.ui.control.Label
        DetailPanel        matlab.ui.container.Panel
        GridLayout_2       matlab.ui.container.GridLayout
        Label_3            matlab.ui.control.Label
        Label_4            matlab.ui.control.Label
        Label_5            matlab.ui.control.Label
        Label_6            matlab.ui.control.Label
        Label_7            matlab.ui.control.Label
        Label_8            matlab.ui.control.Label
        Label_9            matlab.ui.control.Label
        Label_10           matlab.ui.control.Label
        GridLayout_3       matlab.ui.container.GridLayout
        OpenButton         matlab.ui.control.Button
        Button_1           matlab.ui.control.Button
        Label_11           matlab.ui.control.Label
        StateLabel         matlab.ui.control.Label
    end

    properties (SetAccess = private)
        SelectedExperiment = 1
    end

    properties (Access = private)
        Children = cell(1,4)
        Navigation = cell(1,4)
        Capabilities
        Busy = false
        Names = {'实验一 · 统计仿真', '实验二 · 数值求根', ...
            '实验三 · 阵列指向性', '实验四 · 系统稳定性'}
        Accent = [0.12 0.33 0.67]
        Text = [0.12 0.17 0.25]
    end

    methods


        function selectExperiment(app, number)
            validateattributes(number, {'numeric'}, ...
                {'scalar','integer','>=',1,'<=',4});
            app.SelectedExperiment = number;
            for k = 1:4
                selected = k == number;
                app.Navigation{k}.BackgroundColor = ...
                    app.choose(selected, [0.88 0.93 1], [0.96 0.97 0.99]);
                app.Navigation{k}.FontColor = ...
                    app.choose(selected, app.Accent, app.Text);
                app.Navigation{k}.FontWeight = app.choose(selected, 'bold', 'normal');
            end
            scopes = {'统计仿真与音频分析', '实验内容 1 · 牛顿法与二分法', ...
                '实验内容 1 · 均匀线列阵', '全部内容 · 离散与连续系统'};
            details = {
                {'随机仿真','均匀随机变量求和、概率密度、高斯拟合'; ...
                 '音频分析','声道波形、时间窗、均值与方差'; ...
                 '参数','求和项数、重复次数、分箱方式、音频区间'; ...
                 '保存','MAT / CSV 数据、PNG / PDF 图形'}, ...
                {'求根算法','牛顿法、二分法、roots 验证'; ...
                 '计算过程','迭代轨迹、残差、停止原因'; ...
                 '参数','多项式、初值、区间、精度、最大迭代次数'; ...
                 '保存','数据与结果图'}, ...
                {'方向图','直角坐标、极坐标'; ...
                 '幅度','原始幅度、归一化幅度、相对 dB'; ...
                 '参数','阵元数、间距、波长、扫描角与角度采样'; ...
                 '保存','数据与结果图'}, ...
                {'系统分析','零极点、因果系统稳定性'; ...
                 '响应','幅频响应、单位脉冲 / 单位冲激响应'; ...
                 '参数','系统系数、频率范围、时域范围、判定容差'; ...
                 '符号计算','逆 Z / 逆 Laplace 变换、数值结果核验'}};
            app.Heading.Text = app.Names{number};
            app.Scope.Text = scopes{number};
            rows = details{number};
            for row = 1:4
                for column = 1:2
                    app.Capabilities{row,column}.Text = rows{row,column};
                end
            end
            app.OpenButton.Text = '打开实验';
            if app.childIsOpen(number)
                app.OpenButton.Text = '返回已打开的实验';
                app.setStatus('实验窗口已打开', [0.12 0.42 0.29]);
            else
                app.setStatus('就绪', app.Text);
            end
        end

        function child = openExperiment(app, number)
            if nargin < 2, number = app.SelectedExperiment; end
            app.selectExperiment(number);
            if app.Busy
                error('ExperimentHub:Busy', '正在打开实验，请稍候。');
            end
            if app.childIsOpen(number)
                child = app.Children{number};
                child.UIFigure.Visible = 'on';
                figure(child.UIFigure);
                return
            end
            app.Busy = true;
            app.OpenButton.Enable = 'off';
            cleanup = onCleanup(@() app.finishOpening());
            app.setStatus('正在打开实验…', app.Accent);
            drawnow;
            try
                child = launch_experiment(number);
                app.Children{number} = child;
                app.OpenButton.Text = '返回已打开的实验';
                app.setStatus('实验窗口已打开', [0.12 0.42 0.29]);
            catch exception
                app.setStatus(['打开失败：' exception.message], [0.72 0.17 0.17]);
                rethrow(exception);
            end
        end


    end

    methods (Access = private)


        function openSelected(app)
            try
                app.openExperiment();
            catch
                % openExperiment 已在当前界面显示详细错误，保留窗口以便重试。
            end
        end

        function tf = childIsOpen(app, k)
            child = app.Children{k};
            tf = ~isempty(child) && isvalid(child) && ...
                ~isempty(child.UIFigure) && isgraphics(child.UIFigure);
        end

        function finishOpening(app)
            if isvalid(app) && isgraphics(app.UIFigure)
                app.Busy = false;
                app.OpenButton.Enable = 'on';
            end
        end

        function requestClose(app)
            if app.Busy
                app.setStatus('正在打开实验，请完成后再关闭。', app.Accent);
                return
            end
            delete(app);
        end

        function setStatus(app, message, color)
            app.StateLabel.Text = message;
            app.StateLabel.Tooltip = message;
            app.StateLabel.FontColor = color;
        end

        function resize(app)
            % 固定导航宽度，窄窗口由右侧网格滚动保留全部操作。
            if app.UIFigure.Position(3) < 940 || app.UIFigure.Position(4) < 650
                app.MainGrid.RowHeight = {46,32,310,48,52,28};
                app.MainGrid.Scrollable = 'on';
            else
                app.MainGrid.RowHeight = {46,32,'1x',48,52,28};
                app.MainGrid.Scrollable = 'off';
            end
        end

        function openInterface(app)
            root = fileparts(mfilename('fullpath'));
            paths = {fullfile(root,'exp1','docs','接口说明.txt'), ...
                fullfile(root,'exp2','README.md'), fullfile(root,'exp3','README.md'), ...
                fullfile(root,'exp4','README.md')};
            try
                edit(paths{app.SelectedExperiment});
            catch exception
                app.setStatus(exception.message,[0.72 0.17 0.17]);
            end
        end

        function openFolder(app)
            try
                root = fileparts(mfilename('fullpath'));
                if ispc, winopen(root); else, web(root,'-browser'); end
            catch exception
                app.setStatus(exception.message,[0.72 0.17 0.17]);
            end
        end
    end

    methods (Static, Access = private)
        function value = choose(condition, whenTrue, whenFalse)
            if condition, value = whenTrue; else, value = whenFalse; end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Navigation = {app.SelectExperiment1, app.SelectExperiment2, app.SelectExperiment3, app.SelectExperiment4};
            app.Capabilities = {app.Label_3, app.Label_4; app.Label_5, app.Label_6; app.Label_7, app.Label_8; app.Label_9, app.Label_10};
            app.selectExperiment(1);
            app.resize();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.requestClose();
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            app.resize();
        end

        % Button pushed function: SelectExperiment1
        function SelectExperiment1ButtonPushed(app, event)
            app.selectExperiment(1);
        end

        % Button pushed function: SelectExperiment2
        function SelectExperiment2ButtonPushed(app, event)
            app.selectExperiment(2);
        end

        % Button pushed function: SelectExperiment3
        function SelectExperiment3ButtonPushed(app, event)
            app.selectExperiment(3);
        end

        % Button pushed function: SelectExperiment4
        function SelectExperiment4ButtonPushed(app, event)
            app.selectExperiment(4);
        end

        % Button pushed function: Button
        function ButtonButtonPushed(app, event)
            app.openFolder();
        end

        % Button pushed function: OpenButton
        function OpenButtonButtonPushed(app, event)
            app.openSelected();
        end

        % Button pushed function: Button_1
        function Button_1ButtonPushed(app, event)
            app.openInterface();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB 实验 · NWPU 2026';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.UIFigure.Tag = 'ExperimentHub';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.MainGrid = uigridlayout(app.GridLayout);
            app.StateLabel = uilabel(app.MainGrid);
            app.StateLabel.Tag = 'HubStatus';
            app.StateLabel.Tooltip = '就绪';
            app.StateLabel.Text = '就绪';
            app.Label_11 = uilabel(app.MainGrid);
            app.Label_11.Text = '实验参数在各自窗口中设置；可同时打开多个实验。';
            app.GridLayout_3 = uigridlayout(app.MainGrid);
            app.Button_1 = uibutton(app.GridLayout_3, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_1ButtonPushed, true);
            app.Button_1.Text = '接口与运行说明';
            app.OpenButton = uibutton(app.GridLayout_3, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonButtonPushed, true);
            app.OpenButton.Tag = 'OpenExperiment';
            app.OpenButton.Text = '打开实验';
            app.DetailPanel = uipanel(app.MainGrid);
            app.DetailPanel.Title = '实验内容';
            app.GridLayout_2 = uigridlayout(app.DetailPanel);
            app.Label_10 = uilabel(app.GridLayout_2);
            app.Label_10.Text = 'MAT / CSV 数据、PNG / PDF 图形';
            app.Label_9 = uilabel(app.GridLayout_2);
            app.Label_9.Text = '保存';
            app.Label_8 = uilabel(app.GridLayout_2);
            app.Label_8.Text = '求和项数、重复次数、分箱方式、音频区间';
            app.Label_7 = uilabel(app.GridLayout_2);
            app.Label_7.Text = '参数';
            app.Label_6 = uilabel(app.GridLayout_2);
            app.Label_6.Text = '声道波形、时间窗、均值与方差';
            app.Label_5 = uilabel(app.GridLayout_2);
            app.Label_5.Text = '音频分析';
            app.Label_4 = uilabel(app.GridLayout_2);
            app.Label_4.Text = '均匀随机变量求和、概率密度、高斯拟合';
            app.Label_3 = uilabel(app.GridLayout_2);
            app.Label_3.Text = '随机仿真';
            app.Scope = uilabel(app.MainGrid);
            app.Scope.Text = '统计仿真与音频分析';
            app.Heading = uilabel(app.MainGrid);
            app.Heading.Text = '实验一 · 统计仿真';
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout_1 = uigridlayout(app.Panel);
            app.Button = uibutton(app.GridLayout_1, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonButtonPushed, true);
            app.Button.Text = '打开项目文件夹';
            app.Label_2 = uilabel(app.GridLayout_1);
            app.Label_2.Text = '四个独立实验窗口';
            app.SelectExperiment4 = uibutton(app.GridLayout_1, 'push');
            app.SelectExperiment4.ButtonPushedFcn = createCallbackFcn(app, @SelectExperiment4ButtonPushed, true);
            app.SelectExperiment4.Tag = 'SelectExperiment4';
            app.SelectExperiment4.Text = '实验四 · 系统稳定性';
            app.SelectExperiment3 = uibutton(app.GridLayout_1, 'push');
            app.SelectExperiment3.ButtonPushedFcn = createCallbackFcn(app, @SelectExperiment3ButtonPushed, true);
            app.SelectExperiment3.Tag = 'SelectExperiment3';
            app.SelectExperiment3.Text = '实验三 · 阵列指向性';
            app.SelectExperiment2 = uibutton(app.GridLayout_1, 'push');
            app.SelectExperiment2.ButtonPushedFcn = createCallbackFcn(app, @SelectExperiment2ButtonPushed, true);
            app.SelectExperiment2.Tag = 'SelectExperiment2';
            app.SelectExperiment2.Text = '实验二 · 数值求根';
            app.SelectExperiment1 = uibutton(app.GridLayout_1, 'push');
            app.SelectExperiment1.ButtonPushedFcn = createCallbackFcn(app, @SelectExperiment1ButtonPushed, true);
            app.SelectExperiment1.Tag = 'SelectExperiment1';
            app.SelectExperiment1.Text = '实验一 · 统计仿真';
            app.Label_1 = uilabel(app.GridLayout_1);
            app.Label_1.Text = 'NWPU 2026';
            app.Label = uilabel(app.GridLayout_1);
            app.Label.Text = 'MATLAB 实验';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MatlabExperimentHub

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end